classdef SatelliteApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RxGainField                matlab.ui.control.NumericEditField
        RxGainEditFieldLabel       matlab.ui.control.Label
        RequiredSNRField           matlab.ui.control.NumericEditField
        RequiredSNREditFieldLabel  matlab.ui.control.Label
        TxGainField                matlab.ui.control.NumericEditField
        TxGainEditFieldLabel       matlab.ui.control.Label
        FreqGHzField               matlab.ui.control.NumericEditField
        FreqGHzEditFieldLabel      matlab.ui.control.Label
        TxPowerField               matlab.ui.control.NumericEditField
        TxPowerEditFieldLabel      matlab.ui.control.Label
        LinkBudgetParametersLabel  matlab.ui.control.Label
        OrbitPropagatorLabel       matlab.ui.control.Label
        MessageArea                matlab.ui.control.TextArea
        MessageAreaLabel           matlab.ui.control.Label
        ConstellationDropDown      matlab.ui.control.DropDown
        SelectConstellationDropDownLabel  matlab.ui.control.Label
        TLETextArea                matlab.ui.control.TextArea
        TLEName1stline2TLElinesTextAreaLabel  matlab.ui.control.Label
        GSLonField                 matlab.ui.control.NumericEditField
        GSLongitudeEditFieldLabel  matlab.ui.control.Label
        GSLatField                 matlab.ui.control.NumericEditField
        GSLatitudeLabel            matlab.ui.control.Label
        DurationField              matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel  matlab.ui.control.Label
        CalculateLinkBudgetButton  matlab.ui.control.Button
        PropagateButton            matlab.ui.control.Button
        ElevationPlot              matlab.ui.control.UIAxes
        AzimuthPlot                matlab.ui.control.UIAxes
        RangePlot                  matlab.ui.control.UIAxes
        SNRPlot                    matlab.ui.control.UIAxes
        AccessPlot                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PropagateButton
        function PropagateButtonPushed(app, event)
             try
                % Retrieve GUI input values
                simTime = app.DurationField.Value;
                gslat = app.GSLatField.Value;
                gslon = app.GSLonField.Value;
        
                % Extract satellite TLE lines from input area
                tleLines = app.TLETextArea.Value;
                if length(tleLines) < 2
                    error('TLE must include at least two lines per satellite.');
                end
        
                % Write TLE data to a temporary file
                tlePath = fullfile(tempdir, 'temp_tle.txt');
                fid = fopen(tlePath, 'w');
                for i = 1:length(tleLines)
                    fprintf(fid, '%s\n', tleLines{i});
                end
                fclose(fid);
        
                % Simulation parameters
                sampleTime = 60; % seconds
                startTime = datetime('now');
        
                % Clear old viewer window (if any)
                delete(findall(0, 'Type', 'Figure', 'Name', 'Satellite Scenario Viewer'));
        
                % Call the orbit propagation function
                axes(app.AccessPlot);  % Make GUI axes the current target
                [sat, sc] = orbitPropagator(startTime, simTime, sampleTime, tlePath, gslat, gslon, app.AccessPlot);
                axes(app.AccessPlot);

                % Stored values for auxiliar functions like Link Budget
                setappdata(0, 'TLE_StartTime', startTime);
                setappdata(0, 'TLE_SimTime', simTime);
                setappdata(0, 'TLE_SatelliteObj', sat);
                setappdata(0, 'TLE_Scenario', sc);

        
                app.MessageArea.Value = {'Propagation complete.'};
            catch ME
                app.MessageArea.Value = {['Error: ' ME.message]};
            end
        end

        % Button pushed function: CalculateLinkBudgetButton
        function CalculateLinkBudgetButtonPushed(app, event)
            % Get inputs from GUI
            lat     = app.GSLatField.Value;
            lon     = app.GSLonField.Value;
            freqGHz = app.FreqGHzField.Value;
            txPower = app.TxPowerField.Value;
            txGain  = app.TxGainField.Value;
            rxGain  = app.RxGainField.Value;
            reqSNR  = app.RequiredSNRField.Value;
            
            % Load satellite/scenario
            sat = getappdata(0, 'TLE_SatelliteObj');
            sc  = getappdata(0, 'TLE_Scenario');
            
            % Compute SNR time series
            [timeVec, marginVec, azVec, elVec, rangeVec] = computeSNRTimeSeries(sat, sc, lat, lon, freqGHz, txPower, txGain, rxGain, reqSNR);
            
            % Plot on dedicated SNR axes
            plot(app.SNRPlot, timeVec, marginVec, 'LineWidth', 2);
            xlabel(app.SNRPlot, 'Time');
            ylabel(app.SNRPlot, 'Link Margin (dB)');
            title(app.SNRPlot, 'Link Margin Over Time');
            grid(app.SNRPlot, 'on');

            plot(app.AzimuthPlot, timeVec, azVec, 'LineWidth', 2);
            plot(app.ElevationPlot, timeVec, elVec, 'LineWidth', 2);
            plot(app.RangePlot, timeVec, rangeVec, 'LineWidth', 2);
            %%

            % try
            %     % Retrieve stored scenario and satellite
            %     sat = getappdata(0, 'TLE_SatelliteObj');
            %     sc = getappdata(0, 'TLE_Scenario');
            %     if isempty(sat) || isempty(sc)
            %         error('Please propagate the orbit first.');
            %     end
            % 
            %     % Get user input from GUI
            %     lat     = app.GSLatField.Value;
            %     lon     = app.GSLonField.Value;
            %     freqGHz = app.FreqGHzField.Value;
            %     txPower = app.TxPowerField.Value;
            %     txGain  = app.TxGainField.Value;
            %     rxGain  = app.RxGainField.Value;
            %     reqSNR  = app.RequiredSNRField.Value;
            % 
            %     % Call external function
            %     result = computeLinkBudget(sat, sc, lat, lon, freqGHz, ...
            %                                txPower, txGain, rxGain, reqSNR);
            % 
            %     % Show results in GUI
            %     app.MessageArea.Value = {
            %         char(sprintf("Range: %.1f km", result.RangeKm))
            %         char(sprintf("FSPL: %.2f dB", result.FSPL))
            %         char(sprintf("Received Power: %.2f dBW", result.ReceivedPower))
            %         char(sprintf("Link Margin: %.2f dB", result.Margin))
            %     };
            % 
            % 
            % catch ME
            %     app.MessageArea.Value = {['Link budget error: ' ME.message]};
            % end
        end

        % Value changed function: ConstellationDropDown
        function ConstellationDropDownValueChanged(app, event)
            
            selection = lower(app.ConstellationDropDown.Value);

            % Folder where your TLE files are stored
            % Folder where your TLE files are stored
            thisFolder = fileparts(mfilename('fullpath'));
            tleFolder = fullfile(thisFolder, 'tle_files');
            tleFile = fullfile(tleFolder, [selection '.tle']);

            disp([selection '.tle'])
        
            if isfile(tleFile)
                try
                    tleText = readlines(tleFile);  % read entire TLE file
                    app.TLETextArea.Value = tleText;
                    app.MessageArea.Value = {['Loaded TLE for: ' upper(selection)]};
                catch ME
                    app.MessageArea.Value = {['Error reading TLE: ' ME.message]};
                end
            else
                app.TLETextArea.Value = {''};
                app.MessageArea.Value = {['TLE file not found for: ' upper(selection)]};
            end

                    
        end

        % Callback function
        function LoadTLEButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AccessPlot = uiaxes(app.UIFigure);
            title(app.AccessPlot, 'System-wide Access Status')
            xlabel(app.AccessPlot, 'X')
            ylabel(app.AccessPlot, 'Y')
            zlabel(app.AccessPlot, 'Z')
            app.SNRPlot = uiaxes(app.UIFigure);
            title(app.SNRPlot, 'Link Margin (SNR) Over Time')
            xlabel(app.SNRPlot, 'Time')
            ylabel(app.SNRPlot, 'Margin (dB)')
            zlabel(app.SNRPlot, 'Z')
            app.RangePlot = uiaxes(app.UIFigure);
            title(app.RangePlot, 'Range (km)')
            xlabel(app.RangePlot, 'Time')
            zlabel(app.RangePlot, 'Z')
            app.AzimuthPlot = uiaxes(app.UIFigure);
            title(app.AzimuthPlot, 'Azimuth')
            xlabel(app.AzimuthPlot, 'Time')
            zlabel(app.AzimuthPlot, 'Z')
            app.ElevationPlot = uiaxes(app.UIFigure);
            title(app.ElevationPlot, 'Elevation')
            xlabel(app.ElevationPlot, 'Time')
            zlabel(app.ElevationPlot, 'Z')
            app.PropagateButton = uibutton(app.UIFigure, 'push');
            app.PropagateButton.ButtonPushedFcn = createCallbackFcn(app, @PropagateButtonPushed, true);
            app.PropagateButton.Text = 'Propagate Orbit';
            app.CalculateLinkBudgetButton = uibutton(app.UIFigure, 'push');
            app.CalculateLinkBudgetButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateLinkBudgetButtonPushed, true);
            app.CalculateLinkBudgetButton.Text = 'Calculate Link Budget';
            app.DurationsecEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationField = uieditfield(app.UIFigure, 'numeric');
            app.GSLatitudeLabel = uilabel(app.UIFigure);
            app.GSLatitudeLabel.Text = 'GS Latitude (°)';
            app.GSLatField = uieditfield(app.UIFigure, 'numeric');
            app.GSLongitudeEditFieldLabel = uilabel(app.UIFigure);
            app.GSLongitudeEditFieldLabel.Text = 'GS Longitude (°)';
            app.GSLonField = uieditfield(app.UIFigure, 'numeric');
            app.TLEName1stline2TLElinesTextAreaLabel = uilabel(app.UIFigure);
            app.TLEName1stline2TLElinesTextAreaLabel.Text = 'TLE (Name (1st line) + 2 TLE lines';
            app.TLETextArea = uitextarea(app.UIFigure);
            app.SelectConstellationDropDownLabel = uilabel(app.UIFigure);
            app.SelectConstellationDropDownLabel.Text = 'Select Constellation';
            app.ConstellationDropDown = uidropdown(app.UIFigure);
            app.ConstellationDropDown.Items = {'-- No Selection --', 'Galileo', 'GPS', 'Starlink', 'GLONASS'};
            app.ConstellationDropDown.ValueChangedFcn = createCallbackFcn(app, @ConstellationDropDownValueChanged, true);
            app.ConstellationDropDown.Value = '-- No Selection --';
            app.MessageAreaLabel = uilabel(app.UIFigure);
            app.MessageAreaLabel.Text = 'MessageArea';
            app.MessageArea = uitextarea(app.UIFigure);
            app.OrbitPropagatorLabel = uilabel(app.UIFigure);
            app.OrbitPropagatorLabel.Text = 'Orbit Propagator';
            app.LinkBudgetParametersLabel = uilabel(app.UIFigure);
            app.LinkBudgetParametersLabel.Text = 'Link Budget Parameters';
            app.TxPowerEditFieldLabel = uilabel(app.UIFigure);
            app.TxPowerEditFieldLabel.Text = 'Tx Power';
            app.TxPowerField = uieditfield(app.UIFigure, 'numeric');
            app.FreqGHzEditFieldLabel = uilabel(app.UIFigure);
            app.FreqGHzEditFieldLabel.Text = 'Freq (GHz)';
            app.FreqGHzField = uieditfield(app.UIFigure, 'numeric');
            app.TxGainEditFieldLabel = uilabel(app.UIFigure);
            app.TxGainEditFieldLabel.Text = 'Tx Gain';
            app.TxGainField = uieditfield(app.UIFigure, 'numeric');
            app.RequiredSNREditFieldLabel = uilabel(app.UIFigure);
            app.RequiredSNREditFieldLabel.Text = 'Required SNR';
            app.RequiredSNRField = uieditfield(app.UIFigure, 'numeric');
            app.RxGainEditFieldLabel = uilabel(app.UIFigure);
            app.RxGainEditFieldLabel.Text = 'Rx Gain';
            app.RxGainField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SatelliteApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end